classdef ILDAViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ILDAViewerUIFigure   matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        LoadButton           matlab.ui.control.Button
        PrevButton           matlab.ui.control.Button
        NextButton           matlab.ui.control.Button
        DViewCheckBox        matlab.ui.control.CheckBox
        ShowBlankedCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        FrameOffsets % ILDA Frame offsets for current file
        FilePath     % Full path to currently loaded file
        CurrentIndex % Current frame being accessed
    end
    
    methods (Access = private)
        
        function results = readAndDrawFrame(app)
            if (size(app.FrameOffsets) >= 1)
                [x y z s c] = ReadIldaFrame(app.FilePath, app.FrameOffsets(app.CurrentIndex));
                app.UIAxes.cla;
                hold (app.UIAxes, 'on');
                DrawIldaFrame(x, y, z, s, c, app.ShowBlankedCheckBox.Value, app.DViewCheckBox.Value, app.UIAxes);
                hold (app.UIAxes, 'off');
                [n m] = size(app.FrameOffsets);
                title(app.UIAxes, sprintf('Frame %d of %d', app.CurrentIndex, n));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes, 'color', [.2 .2 .2]);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.FrameOffsets = [];
            app.FilePath = [];
            [app.FrameOffsets app.FilePath] = FindIldaFrames();
            if (size(app.FrameOffsets) >= 1)
                app.CurrentIndex = 1;
                app.readAndDrawFrame();
            end
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            if (size(app.FrameOffsets) >= 1)
                app.CurrentIndex = app.CurrentIndex - 1;
                if app.CurrentIndex < 1
                    [n m] = size(app.FrameOffsets);
                    app.CurrentIndex = n;
                end
                app.readAndDrawFrame();
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if (size(app.FrameOffsets) >= 1)
                app.CurrentIndex = app.CurrentIndex + 1;
                if app.CurrentIndex > size(app.FrameOffsets)
                    app.CurrentIndex = 1;
                end
                app.readAndDrawFrame();
            end

        end

        % Value changed function: DViewCheckBox
        function DViewCheckBoxValueChanged(app, event)
            app.readAndDrawFrame();
        end

        % Value changed function: ShowBlankedCheckBox
        function ShowBlankedCheckBoxValueChanged(app, event)
            app.readAndDrawFrame();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ILDAViewerUIFigure = uifigure('Visible', 'off');
            app.ILDAViewerUIFigure.Name = 'ILDA Viewer';
            app.UIAxes = uiaxes(app.ILDAViewerUIFigure);
            title(app.UIAxes, 'Frame 0 of 0')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-32768 32767];
            app.UIAxes.YLim = [-32768 32767];
            app.UIAxes.ZLim = [-32768 32767];
            app.LoadButton = uibutton(app.ILDAViewerUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PrevButton = uibutton(app.ILDAViewerUIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.ILDAViewerUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.DViewCheckBox = uicheckbox(app.ILDAViewerUIFigure);
            app.DViewCheckBox.ValueChangedFcn = createCallbackFcn(app, @DViewCheckBoxValueChanged, true);
            app.DViewCheckBox.Text = '3D View';
            app.ShowBlankedCheckBox = uicheckbox(app.ILDAViewerUIFigure);
            app.ShowBlankedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowBlankedCheckBoxValueChanged, true);
            app.ShowBlankedCheckBox.Text = 'Show Blanked';
            % Show the figure after all components are created
            app.ILDAViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ILDAViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ILDAViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ILDAViewerUIFigure)
        end
    end
end